classdef Code_C1841824 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SettingsPanel                 matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        AutoUpdateLamp                matlab.ui.control.Lamp
        AutoUpdatePlotLabel           matlab.ui.control.Label
        AutoUpdateSwitch              matlab.ui.control.Switch
        OutputValueColorSettingsDropDown  matlab.ui.control.DropDown
        OutputValueColorSettingsDropDownLabel  matlab.ui.control.Label
        OutputValueFontSizeSettingsSlider  matlab.ui.control.Slider
        OutputValueFontSizeSettingsSliderLabel  matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        PlotButton                    matlab.ui.control.Button
        PlotTypeSwitch                matlab.ui.control.Switch
        InteractSwitchLabel           matlab.ui.control.Label
        InteractSwitch                matlab.ui.control.Switch
        UIAxes                        matlab.ui.control.UIAxes
        LeftPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        RatioofSquarestobedisplayedLabel  matlab.ui.control.Label
        EstimateLabel                 matlab.ui.control.Label
        RatioofSquarestobedisplayedSlider  matlab.ui.control.Slider
        NumberofFloorLengthEditFieldLabel  matlab.ui.control.Label
        NumberofFloorLengthEditField  matlab.ui.control.NumericEditField
        NumberofPlanksEditFieldLabel  matlab.ui.control.Label
        NumberofPlanksEditField       matlab.ui.control.NumericEditField
        NumberofSquaresNeedlesEditField  matlab.ui.control.NumericEditField
        NumberofSquaresNeedlesLabel   matlab.ui.control.Label
        SizeofNeedlesSwitch           matlab.ui.control.Switch
        SizeofNeedlesSwitchLabel      matlab.ui.control.Label
        EstimatePiRootSwitch          matlab.ui.control.Switch
        EstimationValueLabel          matlab.ui.control.Label
        NumberofNeedlesOrientationLabel  matlab.ui.control.Label
        NumberofNeedlesSimilarOrientation  matlab.ui.control.NumericEditField
        NamePanel                     matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        ModifiedVersionofBuffonsNeedleLabel  matlab.ui.control.Label
        C1841824GeorgiosKPsevdiotisLabel  matlab.ui.control.Label
        CM2104ComputationalMathematicsLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        SettingsCB                    matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        D; % Diagonal line
        N; % Number of Squares / Needles 
        L; % Length of Square Sides
        nplanks; %Number of planks of wood
        ngrids;
        B; %Distance of the floor / Boundaries
        d; % Distance between planks of wood
        k; % Distance between planks of wood task3
        l; % Length of a Needle
        ratio = 0.01; % Ratio of squares to be displayed instead of N
        n=3; % Needles with most similar orientation
        autoUpdate
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.B = app.NumberofFloorLengthEditField.Value;
            app.nplanks = app.NumberofPlanksEditField.Value;
            app.N = app.NumberofSquaresNeedlesEditField.Value;
            app.D = sqrt((app.L^2)*2);
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%             
            
            ax = app.UIAxes;

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            if app.PlotTypeSwitch.Value == 'Squares'
                
                %GUI CHANGES IF THE PLOT TYPE SELECTES IS SQUARES
                app.NumberofSquaresNeedlesEditField.Limits = [1000000 inf];
                app.NumberofSquaresNeedlesEditField.Value = 1000000;
                app.InteractSwitch.Visible = 0;
                app.InteractSwitchLabel.Visible = 0;
                app.NumberofNeedlesOrientationLabel.Visible = 0;
                app.NumberofNeedlesSimilarOrientation.Visible = 0;
                app.SizeofNeedlesSwitch.Visible = 0;
                app.SizeofNeedlesSwitchLabel.Visible = 0;
                app.EstimatePiRootSwitch.Visible = 1;
                app.EstimationValueLabel.Visible = 1;
                app.NumberofSquaresNeedlesLabel.Text = "Number of Squares to be dropped";
                app.RatioofSquarestobedisplayedLabel.Text = "Ratio of Squares to be displayed";
                app.nplanks = app.NumberofPlanksEditField.Value;
                app.NumberofPlanksEditFieldLabel.Text = "Number of Planks:";
                app.EstimatePiRootSwitch.Enable = "On";
                app.EstimatePiRootSwitch.Visible = "On";
                
                app.nplanks = app.NumberofPlanksEditField.Value;
                app.d = (app.B / (app.nplanks + 1));
                app.L = sqrt((app.d^2)/2);
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %Square created
                x = [-app.L app.L app.L -app.L]/2;
                y = [app.L app.L -app.L -app.L]/2;

                % Formula to translate the squares
                a = sqrt(2*app.L^2);
                b = app.B-a;
                translation = (b-a).*rand(app.N,2)+a;
                
                % Theta to rotate the squares and the rotation
                theta = rand(app.N,1).*90;
                xrotated= x.*cosd(theta(:,1)) + y.*sind(theta(:,1));
                yrotated= -x.*sind(theta(:,1)) + y.*cosd(theta(:,1));
                
                % Rotated and translated squares
                xrt = xrotated(1:app.N,:)+translation(1:app.N,1);
                yrt = yrotated(1:app.N,:)+translation(1:app.N,2);
                
                % Axes Limits on the displayed graph
                ax.XAxis.Limits = [0 app.B];
                ax.YAxis.Limits = [0 app.B+app.d];
                cla(ax);
                
                % X and Y intersect blank arrays to be filled in the for loop
                xi = [0 0 0 0];
                yi = [0 0 0 0];
                % Counter to count the intersects to calculate probability
                count = 0;
                
                % Lines to draw the planks of wood
                planklines = app.d:app.d:app.B-app.d;
                sqintersects = [];
                app.ratio = (app.RatioofSquarestobedisplayedSlider.Value); %Ratio of squares to be shown instead of 1m
                hold(ax,"on");
                
                % For loop to draw the all the plank lines, and calculate
                % the coordinates of the squares that intersect with the
                % planks
                for i = 1:length(planklines)
                        xline(ax,planklines(i));
                        xrtf = find((min(xrt,[],2)<=planklines(i) & (max(xrt,[],2)>=planklines(i))));
                        sqintersects = [sqintersects xrtf'];
                        xfind = xrt(xrtf,:);
                        yfind = yrt(xrtf,:);
                        xi((size(xi,1)+1):(size(xi,1)+size(xfind,1)),:) = xfind;
                        yi((size(yi,1)+1):(size(yi,1)+size(yfind,1)),:) = yfind;
                        count = count + length(xrtf); 
                end
                
                hold(ax,"off");    
                
                transxi = xi';
                transyi = yi';
                
                xrt(sqintersects,:) = [];
                yrt(sqintersects,:) = [];
                
                transxrt = xrt';
                transyrt = yrt';
                
                stepover = size(transxi,2)/(app.N*app.ratio/100);
                
                % Patch of the squares that intersect with planks
                FirstPatch = patch(ax,transxi(:,1:stepover:size(transxi,2)),transyi(:,1:stepover:size(transyi,2)),'red','LineStyle','-','FaceAlpha',0,'EdgeColor',[1 0 0],'FaceAlpha',0);
                
                stepover2 = size(transxrt,2)/(app.N*app.ratio/100);
                
                % Patch of the squares that do notintersect with planks
                SecondPatch = patch(ax,transxrt(:,1:stepover2:size(transxrt,2)),transyrt(:,1:stepover2:size(transyrt,2)),'blue','LineStyle','-','FaceAlpha',0,'EdgeColor',[0 0 1],'FaceAlpha',0);
                
                % Estimated probability of π and √2 
                probability = count/app.N;
                estpi = (4*app.L)/(probability*app.d);
                probabilityroot = ((4*(2*app.L-app.D))/(pi*app.d));
                estroot = (2-probabilityroot/probability);
                
                % If statement to print out the estimations based on the
                % user's choice
                if app.EstimatePiRootSwitch.Value == "π"
                    app.EstimateLabel.Text = ("Estimated value of π is: " + estpi);
                    app.EstimateLabel.Visible = "On";
                elseif app.EstimatePiRootSwitch.Value == "√2"
                    app.EstimateLabel.Text = ("Estimated value of √2 is: " + estroot);
                    app.EstimateLabel.Visible = "On";
                end
                
                
            elseif app.PlotTypeSwitch.Value == 'Needles' %IF NEEDLES
                
                %GUI CHANGES IF THE PLOT TYPE SELECTES IS NEEDLES
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.InteractSwitch.Visible = 1;
                app.InteractSwitchLabel.Visible = 1;
                app.EstimatePiRootSwitch.Visible = 0;
                app.EstimatePiRootSwitch.Enable = 0;
                app.EstimationValueLabel.Visible = 0;
                app.SizeofNeedlesSwitch.Visible = 1;
                app.SizeofNeedlesSwitchLabel.Visible = 1;
                app.SizeofNeedlesSwitch.Enable = 1;
                app.SizeofNeedlesSwitchLabel.Enable = 1;
                app.NumberofSquaresNeedlesLabel.Text = "Number of Needles to be dropped";
                app.RatioofSquarestobedisplayedLabel.Text = "Ratio of Needles to be displayed";
                app.ngrids = app.NumberofPlanksEditField.Value;
                app.NumberofPlanksEditFieldLabel.Text = "Number of Square Tiles:";
                app.EstimatePiRootSwitch.Enable = "Off";
                app.EstimatePiRootSwitch.Visible = "Off";
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                app.k = app.B/sqrt(app.ngrids);
                
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                
                if app.InteractSwitch.Value == "ON" % If the interact button is on it will only plot up to 2.5k needles instead of patch
                    app.NumberofSquaresNeedlesEditField.Limits = [1 2500];
                    app.NumberofSquaresNeedlesEditField.Value = 2500;    % Limits change up to 2.5k
                    app.NumberofNeedlesOrientationLabel.Visible = 1;
                    app.NumberofNeedlesSimilarOrientation.Visible = 1;
                    if app.SizeofNeedlesSwitch.Value == "Fixed" % If the length of the needles is set to fix all the needles will have a fix size
                
                        app.l = app.k;
                        
                        % This is where the Needles are made
                        x = [-app.l app.l]/2;                      
                        y = [0 0]; 
                        
                        % Formula to translate the Needles
                        a=app.l;
                        b=app.B-a;
                        translation = (b-a).*rand(app.N,2)+a;
                        
                        % Theta to rotate the Needles and the rotation
                        theta = rand(app.N,1).*180;
                        xrotated= x.*cosd(theta(:,1)) + y.*sind(theta(:,1));
                        yrotated= -x.*sind(theta(:,1)) + y.*cosd(theta(:,1));
                        
                        % Rotated and translated Needles
                        xrt = xrotated(1:app.N,:)+translation(1:app.N,1);
                        yrt = yrotated(1:app.N,:)+translation(1:app.N,2);
                       
                        % Axes Limits on the displayed graph
                        ax = app.UIAxes;
                        ax.XAxis.Limits = [0 app.B];
                        ax.YAxis.Limits = [0 app.B];
                        cla(ax)
                        
                        % Lines to draw the Gridlines
                        gridlines = app.k:app.k:app.B-app.k;
                        xlines = [app.k:app.k:app.B-app.k];
                        ylines = [app.k:app.k:app.B-app.k];
                       
                        % X and Y intersect blank arrays to be filled in the for loop
                        xi = zeros(1,2);
                        yi = zeros(1,2);
                       
                        needleintersects = [];
                        app.ratio = (app.RatioofSquarestobedisplayedSlider.Value);
                        counter = 0;
                        
                        hold(ax,"on");
                        
                        % For loop to draw the all the grid lines, and calculate
                        % the coordinates of the needles that intersect with the
                        % planks
                        for i=1:length(xlines)
                            xline(ax,gridlines(i));
                            
                            xrtf = find((min(xrt,[],2)<=xlines(i) & (max(xrt,[],2)>=xlines(i))));
                            needleintersects = [needleintersects xrtf'];                    
                            xfind = xrt(xrtf,:);
                            yfind = yrt(xrtf,:);
                            xi((size(xi,1)+1):(size(xi,1)+size(xfind,1)),:) = xfind;
                            yi((size(yi,1)+1):(size(yi,1)+size(yfind,1)),:) = yfind;
                            
                            yline(ax,gridlines(i));
                            
                            yrtf = find((min(yrt,[],2)<=ylines(i) & (max(yrt,[],2)>=ylines(i))));
                            needleintersects = [needleintersects yrtf'];                    
                            xfind2 = xrt(yrtf,:);
                            yfind2 = yrt(yrtf,:);
                            xi((size(xi,1)+1):(size(xi,1)+size(xfind2,1)),:) = xfind2;
                            yi((size(yi,1)+1):(size(yi,1)+size(yfind2,1)),:) = yfind2;
                        end
                        
                        % Counter to count the number of unique
                        % intersection points
                        counter = length(unique(needleintersects.','rows').');
                        
                        transxi = xi';
                        transyi = yi';
                        
                        xrt(needleintersects,:) = [];
                        yrt(needleintersects,:) = [];
                        transxrt = xrt';
                        transyrt = yrt';
                        
                        utrxi = unique(transxi.','rows',"stable").';
                        utryi = unique(transyi.','rows',"stable").';
                        
                        utrxi(:,1) = [];
                        utryi(:,1) = [];    
                        
                        % Plot of the needles that intersect with the
                        % gridlines
                        stepover = size(transxi,2)/(app.N*app.ratio/100);
                        FirstPlot=plot(ax,utrxi(:,1:stepover:size(utrxi,2)),utryi(:,1:stepover:size(utryi,2)),'red');
                        
                        % Plot of the needles that do not intersect with
                        % the gridlines
                        stepover2 = size(transxrt,2)/(app.N*app.ratio/100);
                        SecondPlot=plot(ax,transxrt(:,1:stepover2:size(transxrt,2)),transyrt(:,1:stepover2:size(transyrt,2)),'blue');
                        
                        hold(ax,"off");
                        
                        % Used in function to allow interaction with the
                        % UIAxes objects
                        H = gobjects(1,length(FirstPlot)+length(SecondPlot));
                        H(1:length(FirstPlot)) = FirstPlot;
                        H(length(FirstPlot)+1:length(FirstPlot)+length(SecondPlot)) =  SecondPlot;
                        set(H, 'ButtonDownFcn', {@LineSelected, H});
                        
                        % Estimated probability of π and displayment on the
                        % label
                        prob = counter/app.N;
                        estpineedles = (4*app.l*app.k-app.l^2)/(prob*app.k^2);
                        
                        app.EstimateLabel.Text = ("Estimated value of π is: " + estpineedles);
                        app.EstimateLabel.Visible = "On";
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    elseif app.SizeofNeedlesSwitch.Value == "Mixture" % If the length of the needles is set to mixture all the needles will have a random size
                        
                        % Length of the needles with random lengths
                        app.l = app.k*rand(app.N,1);
                        
                        % This is where the needles are made
                        x = [-app.l(:,1) app.l(:,1)]/2;
                        y = [0 0];
                        
                        % Formula to translate the Needles
                        a=app.l(:,1);
                        b=app.B-a;
                        translation = (b-a).*rand(app.N,2)+a;
                        
                        % Theta to rotate the Needles and the rotation
                        theta = rand(app.N,1).*180;
                        xrotated= x.*cosd(theta(:,1)) + y.*sind(theta(:,1));
                        yrotated= -x.*sind(theta(:,1)) + y.*cosd(theta(:,1));
                        
                        % Rotated and translated Needles
                        xrt = xrotated(1:app.N,:)+translation(1:app.N,1);
                        yrt = yrotated(1:app.N,:)+translation(1:app.N,2);
                        
                        % Axes Limits on the displayed graph
                        ax = app.UIAxes;
                        ax.XAxis.Limits = [0 app.B];
                        ax.YAxis.Limits = [0 app.B];
                        cla(ax)
                        
                        % Lines to draw the Gridlines
                        gridlines = app.k:app.k:app.B-app.k;
                        xlines = [app.k:app.k:app.B-app.k];
                        ylines = [app.k:app.k:app.B-app.k];
                        
                        % X and Y intersect blank arrays to be filled in the for loop
                        xi = zeros(1,2);
                        yi = zeros(1,2);
                        
                        needleintersects = [];
                        app.ratio = (app.RatioofSquarestobedisplayedSlider.Value);
                        counter = 0;
                        hold(ax,"on");
                        
                        % For loop to draw the all the grid lines, and calculate
                        % the coordinates of the needles that intersect with the
                        % planks
                        for i=1:length(xlines)
                            xline(ax,gridlines(i));
                            
                            xrtf = find((min(xrt,[],2)<=xlines(i) & (max(xrt,[],2)>=xlines(i))));
                            needleintersects = [needleintersects xrtf'];                    
                            xfind = xrt(xrtf,:);
                            yfind = yrt(xrtf,:);
                            xi((size(xi,1)+1):(size(xi,1)+size(xfind,1)),:) = xfind;
                            yi((size(yi,1)+1):(size(yi,1)+size(yfind,1)),:) = yfind;
                            
                            yline(ax,gridlines(i));
                            
                            yrtf = find((min(yrt,[],2)<=ylines(i) & (max(yrt,[],2)>=ylines(i))));
                            needleintersects = [needleintersects yrtf'];                    
                            xfind2 = xrt(yrtf,:);
                            yfind2 = yrt(yrtf,:);
                            xi((size(xi,1)+1):(size(xi,1)+size(xfind2,1)),:) = xfind2;
                            yi((size(yi,1)+1):(size(yi,1)+size(yfind2,1)),:) = yfind2;
                        end
                        
                        % Counter to count the number of unique
                        % intersection points
                        counter = length(unique(needleintersects.','rows').');
                        
                        transxi = xi';
                        transyi = yi';
                        transxrt = xrt';
                        transyrt = yrt';
                        
                        utrxi = unique(transxi.','rows',"stable").';
                        utryi = unique(transyi.','rows',"stable").';
                        
                        utrxi(:,1) = [];
                        utryi(:,1) = [];    
                        
                        % Patch of the needles that intersect with the
                        % gridlines
                        stepover = size(transxi,2)/(app.N*app.ratio/100);
                        FirstPlot=plot(ax,utrxi(:,1:stepover:size(utrxi,2)),utryi(:,1:stepover:size(utryi,2)),'red');
                        
                        xrt(needleintersects,:) = [];
                        yrt(needleintersects,:) = [];
                        transxrt = xrt';
                        transyrt = yrt';
                        
                        % Patch of the needles that do not intersect with
                        % the gridlines
                        stepover2 = size(transxrt,2)/(app.N*app.ratio/100);
                        SecondPlot=plot(ax,transxrt(:,1:stepover2:size(transxrt,2)),transyrt(:,1:stepover2:size(transyrt,2)),'blue');
                        
                        hold(ax,"off");
                        
                        % Used in function to allow interaction with the
                        % UIAxes objects
                        H = gobjects(1,length(FirstPlot)+length(SecondPlot));
                        H(1:length(FirstPlot)) = FirstPlot;
                        H(length(FirstPlot)+1:length(FirstPlot)+length(SecondPlot)) =  SecondPlot;
                        set(H, 'ButtonDownFcn', {@LineSelected, H});
                        
                        % Estimated probability of π and displayment on the
                        % label
                        prob = counter/app.N;
                        estpineedles = (4*app.l.*app.k-app.l.^2)/(prob*app.k^2);
                        averagepineedles = sum(estpineedles) / app.N;
                        app.EstimateLabel.Text = ("Estimated value of π is: " + averagepineedles);
                        app.EstimateLabel.Visible = "On";
                    
                    end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                elseif app.InteractSwitch.Value == "OFF" % If the interact button is off it will plot more than 1 million
                    app.NumberofNeedlesOrientationLabel.Visible = 0;
                    app.NumberofNeedlesSimilarOrientation.Visible = 0;
                    app.NumberofSquaresNeedlesEditField.Limits = [1000000 inf]; % Limits change up to infinity
                    app.NumberofSquaresNeedlesEditField.Value = 1000000;
                    
                    if app.SizeofNeedlesSwitch.Value == "Fixed" % If the length of the needles is set to fix all the needles will have a fix size
                        
                        % Length of the needles
                        app.l = app.k;
                        
                        % This is where the needles are made
                        x = [-app.l app.l]/2;                      
                        y = [0 0]; 
                        
                        % Formula to translate the Needles
                        a=app.l;
                        b=app.B-a;
                        
                        
                        % Theta to rotate the Needles and the rotation
                        theta = rand(app.N,1).*180;
                        xrotated= x.*cosd(theta(:,1)) + y.*sind(theta(:,1));
                        yrotated= -x.*sind(theta(:,1)) + y.*cosd(theta(:,1));
                        
                        % Rotated and translated Needles
                        translation = (b-a).*rand(app.N,2)+a;
                        
                        xrt = xrotated(1:app.N,:)+translation(1:app.N,1);
                        yrt = yrotated(1:app.N,:)+translation(1:app.N,2);
                        
                        % Axes Limits on the displayed graph
                        ax = app.UIAxes;
                        ax.XAxis.Limits = [0 app.B];
                        ax.YAxis.Limits = [0 app.B];
                        cla(ax)
                        
                        % Lines to draw the Gridlines
                        gridlines = app.k:app.k:app.B-app.k;
                        xlines = [app.k:app.k:app.B-app.k];
                        ylines = [app.k:app.k:app.B-app.k];
                        
                        % X and Y intersect blank arrays to be filled in the for loop
                        xi = zeros(1,2);
                        yi = zeros(1,2);
                        
                        needleintersects = [];
                        app.ratio = (app.RatioofSquarestobedisplayedSlider.Value);
                        counter = 0;
                        hold(ax,"on");
                        
                        % For loop to draw the all the grid lines, and calculate
                        % the coordinates of the needles that intersect with the
                        % planks
                        for i=1:length(xlines)
                            xline(ax,gridlines(i));
                            
                            xrtf = find((min(xrt,[],2)<=xlines(i) & (max(xrt,[],2)>=xlines(i))));
                            needleintersects = [needleintersects xrtf'];
                            xfind = xrt(xrtf,:);
                            yfind = yrt(xrtf,:);
                            xi((size(xi,1)+1):(size(xi,1)+size(xfind,1)),:) = xfind;
                            yi((size(yi,1)+1):(size(yi,1)+size(yfind,1)),:) = yfind;
                            
                            yline(ax,gridlines(i));
                            
                            yrtf = find((min(yrt,[],2)<=ylines(i) & (max(yrt,[],2)>=ylines(i))));
                            needleintersects = [needleintersects yrtf'];
                            xfind2 = xrt(yrtf,:);
                            yfind2 = yrt(yrtf,:);
                            xi((size(xi,1)+1):(size(xi,1)+size(xfind2,1)),:) = xfind2;
                            yi((size(yi,1)+1):(size(yi,1)+size(yfind2,1)),:) = yfind2;
                        end
                        
                        % Counter to count the number of unique
                        % intersection points
                        counter = length(unique(needleintersects.','rows').');
                        
                        transxi = xi';
                        transyi = yi';
                        
                        
                        % Patch of the needles that intersect with the
                        % gridlines
                        stepover = size(transxi,2)/(app.N*app.ratio/100);
                        FirstPatch = patch(ax,transxi(:,1:stepover:size(transxi,2)),transyi(:,1:stepover:size(transyi,2)),'red','LineStyle','-','FaceAlpha',0,'EdgeColor',[1 0 0],'FaceAlpha',0);
                        
                        xrt(needleintersects,:) = [];
                        yrt(needleintersects,:) = [];
                        transxrt = xrt';
                        transyrt = yrt';
                        
                        % Patch of the needles that do not intersect with
                        % the gridlines
                        stepover2 = size(transxrt,2)/(app.N*app.ratio/100);
                        SecondPatch = patch(ax,transxrt(:,1:stepover2:size(transxrt,2)),transyrt(:,1:stepover2:size(transyrt,2)),'blue','LineStyle','-','FaceAlpha',0,'EdgeColor',[0 0 1],'FaceAlpha',0);
                        
                        utrxi = unique(transxi.','rows',"stable").';
                        utryi = unique(transyi.','rows',"stable").';
                        
                        utrxi(:,1) = [];
                        utryi(:,1) = [];
                        
                        hold(ax,"off");
                        
                        % Estimated probability of π and displayment on the
                        % label
                        prob = counter/app.N;
                        estpineedles = (4*app.l*app.k-app.l^2)/(prob*app.k^2);
                        app.EstimateLabel.Text = ("Estimated value of π is: " + estpineedles);
                        app.EstimateLabel.Visible = "On";
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    elseif app.SizeofNeedlesSwitch.Value == "Mixture" % If the length of the needles is set to mixtures all the needles will have a random size
                        
                        % Length of the needles with random lengths
                        app.l = app.k*rand(app.N,1);
                        
                        % This is where the needles are made
                        x = [-app.l(:,1) app.l(:,1)]/2;
                        y = [0 0];
                        
                        % Formula to translate the Needles
                        a=app.l(:,1);
                        b=app.B-a;
                        
                        % Theta to rotate the Needles and the rotation
                        theta = rand(app.N,1).*180;
                        xrotated= x.*cosd(theta(:,1)) + y.*sind(theta(:,1));
                        yrotated= -x.*sind(theta(:,1)) + y.*cosd(theta(:,1));
                        
                        % Rotated and translated Needles
                        translation = (b-a).*rand(app.N,2)+a;
                        xrt = xrotated(1:app.N,:)+translation(1:app.N,1);
                        yrt = yrotated(1:app.N,:)+translation(1:app.N,2);
                        
                        % Axes Limits on the displayed graph
                        ax = app.UIAxes;
                        ax.XAxis.Limits = [0 app.B];
                        ax.YAxis.Limits = [0 app.B];
                        cla(ax)
                        
                        % Lines to draw the Gridlines
                        gridlines = app.k:app.k:app.B-app.k;
                        xlines = [app.k:app.k:app.B-app.k];
                        ylines = [app.k:app.k:app.B-app.k];
                        
                        % X and Y intersect blank arrays to be filled in the for loop
                        xi = zeros(1,2);
                        yi = zeros(1,2);
                        
                        needleintersects = [];
                        app.ratio = (app.RatioofSquarestobedisplayedSlider.Value);
                        counter = 0;
                        
                        hold(ax,"on");
                        
                        % For loop to draw the all the grid lines, and calculate
                        % the coordinates of the needles that intersect with the
                        % planks
                        for i=1:length(xlines)
                            xline(ax,gridlines(i));
                            
                            xrtf = find((min(xrt,[],2)<=xlines(i) & (max(xrt,[],2)>=xlines(i))));
                            needleintersects = [needleintersects xrtf'];
                            xfind = xrt(xrtf,:);
                            yfind = yrt(xrtf,:);
                            xi((size(xi,1)+1):(size(xi,1)+size(xfind,1)),:) = xfind;
                            yi((size(yi,1)+1):(size(yi,1)+size(yfind,1)),:) = yfind;
                            
                            yline(ax,gridlines(i));
                            
                            yrtf = find((min(yrt,[],2)<=ylines(i) & (max(yrt,[],2)>=ylines(i))));
                            needleintersects = [needleintersects yrtf'];
                            xfind2 = xrt(yrtf,:);
                            yfind2 = yrt(yrtf,:);
                            xi((size(xi,1)+1):(size(xi,1)+size(xfind2,1)),:) = xfind2;
                            yi((size(yi,1)+1):(size(yi,1)+size(yfind2,1)),:) = yfind2;
                        end
                        
                        % Counter to count the number of unique
                        % intersection points
                        counter = length(unique(needleintersects.','rows').');
                        
                        transxi = xi';
                        transyi = yi';
                        transxrt = xrt';
                        transyrt = yrt';
                        
                        
                        % Patch of the needles that intersect with the
                        % gridlines
                        FirstPatchNeedles=patch(ax,transxi(:,1:size(transxi,2)/(app.N*app.ratio):size(transxi,2)),transyi(:,1:size(transyi,2)/(app.N*app.ratio):size(transyi,2)),'r','LineStyle','-','FaceAlpha',0.1,'EdgeColor',[1,0,0]);
                        xrt(needleintersects,:) = [];
                        yrt(needleintersects,:) = [];
                        transxrt = xrt';
                        transyrt = yrt';
                        
                        % Patch of the needles that do not intersect with
                        % the gridlines
                        SecondPatchNeedles=patch(ax,transxrt(:,1:size(transxrt,2)/(app.N*app.ratio):size(transxrt,2)),transyrt(:,1:size(transxrt,2)/(app.N*app.ratio):size(transyrt,2)),'b','LineStyle','-','FaceAlpha',0,'EdgeColor',[0 0 1]);
                        
                        hold(ax,"off");
                        
                        % Estimated probability of π and displayment on the
                        % label
                        prob = counter/app.N;
                        estpineedles = (4*app.l.*app.k-app.l.^2)/(prob*app.k^2);
                        averagepineedles = sum(estpineedles) / app.N;
                        app.EstimateLabel.Text = ("Estimated value of π is: " + averagepineedles);
                        app.EstimateLabel.Visible = "On";
                    end
                end
            end
             
            % Function that interacts with the UIAxes
            % Set the selected colour of interracted to green
            % and back to red afterwards.
            % Creates a table of zeros that stores inside x data y data
            % values, slope values, sorted and compares to see which have
            % similar slope to highlight
             function LineSelected(ObjectH, ~, H)
                set(ObjectH, 'Color', [0 1 0],'LineWidth',2.5)
                set(H(H ~= ObjectH), 'Color', [1 0 0],'LineWidth',0.5) ;
                x = ObjectH.XData;
                y = ObjectH.YData;
                lineslope = (y(2)-y(1))/(x(2)-x(1));
                tbl = zeros(length(H),7);
                for i=1:length(H)
                    data = H(1,i).XData;
                    tbl(i,1) = data(1);
                    tbl(i,2) = data(2);
                    data = H(1,i).YData;
                    tbl(i,3) = data(1);
                    tbl(i,4) = data(2);
                    tbl(i,5) = (tbl(i,4)-tbl(i,3))/(tbl(i,2)-tbl(i,1));
                    tbl(i,6) = abs(tbl(i,5)-lineslope);
                    tbl(i,7) = i;
                end
                tbl(1,:) = [];
                 sortedrows = sortrows(tbl,6);
                for j = 2:(app.n + 1)
                    set(H(sortedrows(j,7)),'Color',[0 0 0],'LineWidth',3);
                end
              end
       
    end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AutoUpdateSwitch.Value = 'ON';
            app.SettingsCB.Value = 0;
            app.PlotTypeSwitch.Value = 'Squares' ;
            app.SizeofNeedlesSwitch.Enable = 0;
            app.InteractSwitch.Visible = 0;
            app.InteractSwitchLabel.Visible = 0;
            app.NumberofNeedlesSimilarOrientation.Visible = 0;
            app.NumberofNeedlesOrientationLabel.Visible = 0;
            app.SizeofNeedlesSwitchLabel.Enable = 0;
            app.SizeofNeedlesSwitch.Visible = 0;
            app.SizeofNeedlesSwitchLabel.Visible = 0;
            app.EstimationValueLabel.Visible = 1;
            app.EstimatePiRootSwitch.Visible = 1;
            app.PlotButton.Enable = 'OFF' ;
            app.PlotButton.Visible='OFF';
            app.SettingsPanel.Visible = 0;
            app.nplanks = app.NumberofPlanksEditField.Value;
            app.autoUpdate = 1 ;
            updatePlot(app)
        end

        % Value changed function: AutoUpdateSwitch
        function AutoUpdateSwitchValueChanged(app, event)
            if strcmp(app.AutoUpdateSwitch.Value, 'ON')
                app.autoUpdate = 1 ;
                app.PlotButton.Enable = 'off' ;
                app.PlotButton.Visible = 'off';
                app.AutoUpdateLamp.Color = [0 1 0] ;
            elseif strcmp(app.AutoUpdateSwitch.Value, 'OFF')
                app.autoUpdate = 0 ;
                app.PlotButton.Enable = 'on' ;
                app.PlotButton.Visible = 'on';
                app.AutoUpdateLamp.Color = [0.5 0.5 0.5] ;
            end        
        end

        % Value changed function: NumberofFloorLengthEditField
        function FloorLengthFcn(app, event)
           value = app.NumberofFloorLengthEditField.Value; 
           app.B = value;
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: NumberofPlanksEditField
        function PlankGridsFcn(app, event)
           value = app.NumberofPlanksEditField.Value; 
           app.nplanks = value;
            if app.autoUpdate
                updatePlot(app)
             end
        end

        % Value changed function: NumberofSquaresNeedlesEditField
        function NumberFcn(app, event)
           value = app.NumberofSquaresNeedlesEditField.Value; 
           app.N = value;
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: PlotTypeSwitch
        function PlotTypeSwitchFcn(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changed function: RatioofSquarestobedisplayedSlider
        function RatioSliderFcn(app, event)
            if app.autoUpdate
               updatePlot(app)
           end
        end

        % Button pushed function: PlotButton
        function PlotBtnFcn(app, event)
            updatePlot(app)
        end

        % Callback function
        function PiRootSwFcn(app, event)
            if app.autoUpdate
               updatePlot(app)
            end
        end

        % Value changed function: SettingsCB
        function SettingsFcn(app, event)
            if app.SettingsCB.Value == 0
                app.ModifiedVersionofBuffonsNeedleLabel.Text = "Modified Version of Buffon's Needle";
                app.ModifiedVersionofBuffonsNeedleLabel.FontColor = "black";
                app.LeftPanel.Visible = 1;
                app.RightPanel.Visible = 1;
                app.SettingsPanel.Visible = 0;
            elseif app.SettingsCB.Value == 1
                app.ModifiedVersionofBuffonsNeedleLabel.Text = "Settings Mode";
                app.ModifiedVersionofBuffonsNeedleLabel.FontColor = "blue";
                app.LeftPanel.Visible = 0;
                app.RightPanel.Visible = 0;
                app.SettingsPanel.Visible = 1;
            end
            
        end

        % Value changed function: OutputValueColorSettingsDropDown
        function ColorDropFcn(app, event)
            value = app.OutputValueColorSettingsDropDown.Value;
            app.EstimateLabel.FontColor = value;
        end

        % Value changed function: OutputValueFontSizeSettingsSlider
        function SliderFontSizeFcs(app, event)
            value = app.OutputValueFontSizeSettingsSlider.Value;
            app.EstimateLabel.FontSize = value;
        end

        % Value changed function: SizeofNeedlesSwitch
        function SizeOfNeedleFcn(app, event)
            if app.autoUpdate
               updatePlot(app)
            end
        end

        % Value changed function: EstimatePiRootSwitch
        function EstPiRoot(app, event)
            if app.autoUpdate
               updatePlot(app)
            end
        end

        % Size changed function: RightPanel
        function RightPanelFcn(app, event)
            position = app.RightPanel.Position;
            p = app.RightPanel;
            p.Units = normalized;
        end

        % Size changed function: LeftPanel
        function LeftPanelFcn(app, event)
            position = app.LeftPanel.Position;
            
        end

        % Value changed function: NumberofNeedlesSimilarOrientation
        function SimilarOrientatioFcn(app, event)
            value = app.NumberofNeedlesSimilarOrientation.Value;
            app.n = value;
        end

        % Value changed function: InteractSwitch
        function InteractFcn(app, event)
            if app.autoUpdate
               updatePlot(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.SettingsPanel);
            app.AutoUpdateLamp = uilamp(app.GridLayout);
            app.AutoUpdatePlotLabel = uilabel(app.GridLayout);
            app.AutoUpdatePlotLabel.Text = 'Auto Update Plot';
            app.AutoUpdateSwitch = uiswitch(app.GridLayout, 'slider');
            app.AutoUpdateSwitch.Items = {'OFF', 'ON'};
            app.AutoUpdateSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoUpdateSwitchValueChanged, true);
            app.AutoUpdateSwitch.Value = 'OFF';
            app.OutputValueColorSettingsDropDown = uidropdown(app.GridLayout);
            app.OutputValueColorSettingsDropDown.Items = {'Black', 'Red', 'Green', 'Blue', 'Cyan', 'Yellow'};
            app.OutputValueColorSettingsDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropFcn, true);
            app.OutputValueColorSettingsDropDown.Value = 'Black';
            app.OutputValueColorSettingsDropDownLabel = uilabel(app.GridLayout);
            app.OutputValueColorSettingsDropDownLabel.Text = 'Output Value Color Settings';
            app.OutputValueFontSizeSettingsSlider = uislider(app.GridLayout);
            app.OutputValueFontSizeSettingsSlider.Limits = [1 60];
            app.OutputValueFontSizeSettingsSlider.MajorTicks = [1 5 10 15 20 25 30 35 40 45 50 55 60];
            app.OutputValueFontSizeSettingsSlider.MajorTickLabels = {'1', '5', '10', '15', '20', '25', '30', '35', '40', '45', '50', '55', '60'};
            app.OutputValueFontSizeSettingsSlider.ValueChangedFcn = createCallbackFcn(app, @SliderFontSizeFcs, true);
            app.OutputValueFontSizeSettingsSlider.Value = 22;
            app.OutputValueFontSizeSettingsSliderLabel = uilabel(app.GridLayout);
            app.OutputValueFontSizeSettingsSliderLabel.Text = 'Output Value Font Size Settings';
            app.RightPanel = uipanel(app.UIFigure);
            app.RightPanel.SizeChangedFcn = createCallbackFcn(app, @RightPanelFcn, true);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.PlotButton = uibutton(app.GridLayout3, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBtnFcn, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.PlotTypeSwitch = uiswitch(app.GridLayout3, 'slider');
            app.PlotTypeSwitch.Items = {'Squares', 'Needles'};
            app.PlotTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotTypeSwitchFcn, true);
            app.PlotTypeSwitch.Value = 'Squares';
            app.InteractSwitchLabel = uilabel(app.GridLayout3);
            app.InteractSwitchLabel.Text = 'Interact';
            app.InteractSwitch = uiswitch(app.GridLayout3, 'slider');
            app.InteractSwitch.Items = {'OFF', 'ON'};
            app.InteractSwitch.ValueChangedFcn = createCallbackFcn(app, @InteractFcn, true);
            app.InteractSwitch.Value = 'OFF';
            app.UIAxes = uiaxes(app.GridLayout3);
            app.UIAxes.YTick = [];
            app.LeftPanel = uipanel(app.UIFigure);
            app.LeftPanel.SizeChangedFcn = createCallbackFcn(app, @LeftPanelFcn, true);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.RatioofSquarestobedisplayedLabel = uilabel(app.GridLayout2);
            app.RatioofSquarestobedisplayedLabel.Text = {'Ratio of Squares'; 'to be displayed'};
            app.EstimateLabel = uilabel(app.GridLayout2);
            app.EstimateLabel.Text = 'Estimate';
            app.RatioofSquarestobedisplayedSlider = uislider(app.GridLayout2);
            app.RatioofSquarestobedisplayedSlider.Limits = [0.01 100];
            app.RatioofSquarestobedisplayedSlider.MajorTicks = [0.01 20 40 60 80 100];
            app.RatioofSquarestobedisplayedSlider.MajorTickLabels = {'0.01', '20', '40', '60', '80', '100'};
            app.RatioofSquarestobedisplayedSlider.ValueChangedFcn = createCallbackFcn(app, @RatioSliderFcn, true);
            app.RatioofSquarestobedisplayedSlider.Value = 0.01;
            app.NumberofFloorLengthEditFieldLabel = uilabel(app.GridLayout2);
            app.NumberofFloorLengthEditFieldLabel.Text = 'Number of Floor Length:';
            app.NumberofFloorLengthEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NumberofFloorLengthEditField.Limits = [1 Inf];
            app.NumberofFloorLengthEditField.ValueDisplayFormat = '%d';
            app.NumberofFloorLengthEditField.ValueChangedFcn = createCallbackFcn(app, @FloorLengthFcn, true);
            app.NumberofFloorLengthEditField.Value = 10;
            app.NumberofPlanksEditFieldLabel = uilabel(app.GridLayout2);
            app.NumberofPlanksEditFieldLabel.Text = 'Number of Planks';
            app.NumberofPlanksEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NumberofPlanksEditField.Limits = [1 Inf];
            app.NumberofPlanksEditField.ValueDisplayFormat = '%d';
            app.NumberofPlanksEditField.ValueChangedFcn = createCallbackFcn(app, @PlankGridsFcn, true);
            app.NumberofPlanksEditField.Value = 100;
            app.NumberofSquaresNeedlesEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NumberofSquaresNeedlesEditField.Limits = [0 Inf];
            app.NumberofSquaresNeedlesEditField.ValueDisplayFormat = '%d';
            app.NumberofSquaresNeedlesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberFcn, true);
            app.NumberofSquaresNeedlesEditField.Value = 1000000;
            app.NumberofSquaresNeedlesLabel = uilabel(app.GridLayout2);
            app.NumberofSquaresNeedlesLabel.Text = 'Number of Squares / Needles';
            app.SizeofNeedlesSwitch = uiswitch(app.GridLayout2, 'slider');
            app.SizeofNeedlesSwitch.Items = {'Fixed', 'Mixture'};
            app.SizeofNeedlesSwitch.ValueChangedFcn = createCallbackFcn(app, @SizeOfNeedleFcn, true);
            app.SizeofNeedlesSwitch.Value = 'Fixed';
            app.SizeofNeedlesSwitchLabel = uilabel(app.GridLayout2);
            app.SizeofNeedlesSwitchLabel.Text = 'Size of Needles';
            app.EstimatePiRootSwitch = uiswitch(app.GridLayout2, 'slider');
            app.EstimatePiRootSwitch.Items = {'π', '√2'};
            app.EstimatePiRootSwitch.ValueChangedFcn = createCallbackFcn(app, @EstPiRoot, true);
            app.EstimatePiRootSwitch.Value = 'π';
            app.EstimationValueLabel = uilabel(app.GridLayout2);
            app.EstimationValueLabel.Text = 'Estimation Value';
            app.NumberofNeedlesOrientationLabel = uilabel(app.GridLayout2);
            app.NumberofNeedlesOrientationLabel.Text = 'Number of needles similar orientation';
            app.NumberofNeedlesSimilarOrientation = uieditfield(app.GridLayout2, 'numeric');
            app.NumberofNeedlesSimilarOrientation.Limits = [1 Inf];
            app.NumberofNeedlesSimilarOrientation.ValueDisplayFormat = '%d';
            app.NumberofNeedlesSimilarOrientation.ValueChangedFcn = createCallbackFcn(app, @SimilarOrientatioFcn, true);
            app.NumberofNeedlesSimilarOrientation.Value = 3;
            app.NamePanel = uipanel(app.UIFigure);
            app.GridLayout4 = uigridlayout(app.NamePanel);
            app.ModifiedVersionofBuffonsNeedleLabel = uilabel(app.GridLayout4);
            app.ModifiedVersionofBuffonsNeedleLabel.Text = {'Modified Version'; 'of Buffon''s Needle'};
            app.C1841824GeorgiosKPsevdiotisLabel = uilabel(app.GridLayout4);
            app.C1841824GeorgiosKPsevdiotisLabel.Text = {'C1841824'; 'Georgios K. Psevdiotis'};
            app.CM2104ComputationalMathematicsLabel = uilabel(app.GridLayout4);
            app.CM2104ComputationalMathematicsLabel.Text = {'CM2104'; 'Computational Mathematics'; ''; ''};
            app.Image = uiimage(app.GridLayout4);
            app.Image.ImageSource = 'Settings.gif';
            app.SettingsCB = uicheckbox(app.GridLayout4);
            app.SettingsCB.ValueChangedFcn = createCallbackFcn(app, @SettingsFcn, true);
            app.SettingsCB.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Code_C1841824

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end